classdef BIDS_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        NameEditField_2Label            matlab.ui.control.Label
        StudyNameEditField              matlab.ui.control.EditField
        BIDSVersionEditFieldLabel       matlab.ui.control.Label
        BIDSVersionEditField            matlab.ui.control.EditField
        LicenseEditFieldLabel           matlab.ui.control.Label
        LicenseEditField                matlab.ui.control.EditField
        AuthorsEditFieldLabel           matlab.ui.control.Label
        AuthorsEditField                matlab.ui.control.EditField
        AcknowledgementsTextAreaLabel   matlab.ui.control.Label
        AcknowledgementsTextArea        matlab.ui.control.TextArea
        FundingTextAreaLabel            matlab.ui.control.Label
        FundingTextArea                 matlab.ui.control.TextArea
        ReferenceandLinksTextAreaLabel  matlab.ui.control.Label
        ReferenceandLinksTextArea       matlab.ui.control.TextArea
        DatasetDOIEditFieldLabel        matlab.ui.control.Label
        DatasetDOIEditField             matlab.ui.control.EditField
        InstitutionNameEditFieldLabel   matlab.ui.control.Label
        InstitutionNameEditField        matlab.ui.control.EditField
        DepartmentNameEditFieldLabel    matlab.ui.control.Label
        DepartmentNameEditField         matlab.ui.control.EditField
        InstitutionAddressEditFieldLabel  matlab.ui.control.Label
        InstitutionAddressEditField     matlab.ui.control.EditField
        TaskDescriptionTextAreaLabel    matlab.ui.control.Label
        TaskDescriptionTextArea         matlab.ui.control.TextArea
        FolderStructureTab              matlab.ui.container.Tab
        StudyFolderBrowserButton        matlab.ui.control.Button
        StudyFolderEditFieldLabel       matlab.ui.control.Label
        StudyFolderEditField            matlab.ui.control.EditField
        FilenameprefixEditFieldLabel    matlab.ui.control.Label
        FilenameprefixEditField         matlab.ui.control.EditField
        RawDatafolderEditFieldLabel     matlab.ui.control.Label
        RawDatafolderEditField          matlab.ui.control.EditField
        ChannellocationsfilenameEditFieldLabel  matlab.ui.control.Label
        ChannellocationsfilenameEditField  matlab.ui.control.EditField
        BIDSdatafolderEditFieldLabel    matlab.ui.control.Label
        BIDSdatafolderEditField         matlab.ui.control.EditField
        EEGkeywordEditFieldLabel        matlab.ui.control.Label
        EEGkeywordEditField             matlab.ui.control.EditField
        TasklabelEditFieldLabel         matlab.ui.control.Label
        TasklabelEditField              matlab.ui.control.EditField
        SessionandRigidbodyTab          matlab.ui.container.Tab
        RigidbodyPanel                  matlab.ui.container.Panel
        RigidAddButton                  matlab.ui.control.Button
        RigidRemoveButton               matlab.ui.control.Button
        SessionPanel                    matlab.ui.container.Panel
        SessionAddButton                matlab.ui.control.Button
        SessionRemoveButton             matlab.ui.control.Button
        SessionSortButton               matlab.ui.control.Button
        SessionRidigbodyTable           matlab.ui.control.Table
        EEGTab                          matlab.ui.container.Tab
        PowerLineFrequencyEditFieldLabel  matlab.ui.control.Label
        PowerLineFrequencyEditField     matlab.ui.control.NumericEditField
        EEGReferenceEditFieldLabel      matlab.ui.control.Label
        EEGReferenceEditField           matlab.ui.control.EditField
        MotionSystemTab                 matlab.ui.container.Tab
        AcquisitionLabelEditFieldLabel  matlab.ui.control.Label
        AcquisitionLabelEditField       matlab.ui.control.EditField
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        SystemNameEditFieldLabel        matlab.ui.control.Label
        SystemNameEditField             matlab.ui.control.EditField
        CoordinateSystemEditFieldLabel  matlab.ui.control.Label
        CoordinateSystemEditField       matlab.ui.control.EditField
        RotationRuleEditFieldLabel      matlab.ui.control.Label
        RotationRuleEditField           matlab.ui.control.EditField
        RotationOrderEditFieldLabel     matlab.ui.control.Label
        RotationOrderEditField          matlab.ui.control.EditField
        RecordingTypeDropDownLabel      matlab.ui.control.Label
        RecordingTypeDropDown           matlab.ui.control.DropDown
        ParticipantTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ValuesTab                       matlab.ui.container.Tab
        SubjectDataTable                matlab.ui.control.Table
        LoadDataButton                  matlab.ui.control.Button
        MetadataTab                     matlab.ui.container.Tab
        FeatureDescriptionPanel         matlab.ui.container.Panel
        FeatureUnitEditFieldLabel       matlab.ui.control.Label
        FeatureUnitEditField            matlab.ui.control.EditField
        FeatureDescriptionTextAreaLabel  matlab.ui.control.Label
        FeatureDescriptionTextArea      matlab.ui.control.TextArea
        FeaturesLevelsPanel             matlab.ui.container.Panel
        FeaturesPanel                   matlab.ui.container.Panel
        SubjectFeatureSearchfromfileButton  matlab.ui.control.Button
        SubjectFeatureRemoveButton      matlab.ui.control.Button
        SubjectFeatureAddButton         matlab.ui.control.Button
        SubjectFeatureEditButton        matlab.ui.control.Button
        SubjectFeatureTree              matlab.ui.container.Tree
        LevelsPanel                     matlab.ui.container.Panel
        SubjectLevelAddButton           matlab.ui.control.Button
        SubjectLevelRemoveButton        matlab.ui.control.Button
        SubjectLevelEditButton          matlab.ui.control.Button
        LevelDescriptionPanel           matlab.ui.container.Panel
        LevelNameEditFieldLabel         matlab.ui.control.Label
        LevelNameEditField              matlab.ui.control.EditField
        ConfigSaveButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        generalInfo = struct();
        bemobil_config = struct();
        eegInfo = struct();
        motionInfo = struct();
        subjectInfo = struct();
        
        % Session variables
        sessRigidData = struct('Name', '', 'PositionUnit', '', 'OrientationUnit', '');
        sessRigidTableRow = 0;
        sessRigidTableCol = 0;
        rigidBodyCount = 0;
        
        % Feature variables
        activeFeature = '';
        activeLevel = '';
    end
    
    methods (Access = private)
        
        function generateConfigStructs(app)
            % General Info 
            % required for dataset_description.json
            app.generalInfo.dataset_description.Name            = app.StudyNameEditField.Value;
            app.generalInfo.dataset_description.BIDSVersion     = app.BIDSVersionEditField.Value;
            % optional for dataset_description.json
            app.generalInfo.dataset_description.License         = app.LicenseEditField.Value;
            app.generalInfo.dataset_description.Authors             = app.AuthorsEditField.Value;
            app.generalInfo.dataset_description.Acknowledgements    = app.AcknowledgementsTextArea.Value;
            app.generalInfo.dataset_description.Funding             = app.FundingTextArea.Value;
            app.generalInfo.dataset_description.ReferencesAndLinks  = app.ReferenceandLinksTextArea.Value;
            app.generalInfo.dataset_description.DatasetDOI          = app.DatasetDOIEditField.Value;
            % general information shared across modality specific json files 
            app.generalInfo.InstitutionName                         = app.InstitutionNameEditField.Value;
            app.generalInfo.InstitutionalDepartmentName             = app.DepartmentNameEditField.Value;
            app.generalInfo.InstitutionAddress                      = app.InstitutionAddressEditField.Value;
            app.generalInfo.TaskDescription                         = app.TaskDescriptionTextArea.Value;

            
            % Folder and file structure
            app.bemobil_config.study_folder             = app.StudyFolderEditField.Value;
            app.bemobil_config.filename_prefix          = app.FilenameprefixEditField.Value;
            app.bemobil_config.raw_data_folder          = app.RawDatafolderEditField.Value;
            app.bemobil_config.bids_data_folder         = app.BIDSdatafolderEditField.Value;
            app.bemobil_config.channel_locations_filename = app.ChannellocationsfilenameEditField.Value;
            app.bemobil_config.bids_eegkeyword          = app.EEGkeywordEditField.Value;
            app.bemobil_config.bids_tasklabel           = app.TasklabelEditField.Value;
            
            
            % Session and rigidbody info
            app.bemobil_config.rigidbody_streams        = app.SessionRidigbodyTable.ColumnName(4:end);
            app.bemobil_config.filenames                = app.SessionRidigbodyTable.Data.Name;
            app.bemobil_config.bids_rbsessions                 = app.SessionRidigbodyTable.Data{:,4:end};
            app.bemobil_config.bids_motion_positionunits       = app.SessionRidigbodyTable.Data.PositionUnit;
            app.bemobil_config.bids_motion_orientationunits    = app.SessionRidigbodyTable.Data.OrientationUnit;
            
            
            % information about the EEG recording system 
            % coordinate system
            app.eegInfo.eeg.PowerLineFequency                       = app.PowerLineFrequencyEditField.Value;
            app.eegInfo.eeg.EEGReference                            = app.EEGReferenceEditField.Value;
            
            % information about the motion recording system 
            % data type and acquisition label
            app.motionInfo.acq                                     = app.AcquisitionLabelEditField.Value;
            % motion specific fields in json
            app.motionInfo.motion.Manufacturer                     = app.ManufacturerEditField.Value;
            app.motionInfo.motion.ManufacturersModelName           = app.SystemNameEditField.Value;
            app.motionInfo.motion.RecordingType                    = app.RecordingTypeDropDown.Value;
            % coordinate system
            app.motionInfo.coordsystem.MotionCoordinateSystem      = app.CoordinateSystemEditField.Value;
            app.motionInfo.coordsystem.MotionRotationRule          = app.RotationRuleEditField.Value;
            app.motionInfo.coordsystem.MotionRotationOrder         = app.RotationOrderEditField.Value;

            % subject info
            app.subjectInfo.cols = app.SubjectDataTable.ColumnName;
            app.subjectInfo.data = table2cell(app.SubjectDataTable.Data);
        end
        
        function valueString = generateConfigString(app, structValue)
            if iscell(structValue)
                structValue = strjoin(structValue);
            end
            valueString = [' = ''' structValue ''';\n'];
        end
        
        function quoteCell = addQuote(app, cellArray)
            for i=1:length(cellArray{1})
                cellArray{1}{i} = ['''' cellArray{1}{i} ''''];
            end
            quoteCell = cellArray;
        end
        
        function valueString = generateConfigCell(app, structValue)
            structValue = addQuote(app, {structValue});
            structValue = strjoin(structValue{1}, ',');
            valueString = [' = {' structValue '};\n'];
        end


        function arrayString = generateConfigArray(app, array)
            if isempty(array)
                arrayString = ' = [];\n';
                return
            end
            if iscell(array)
                array = cell2mat(array);
            end
            arrayString = ' = [ ';
            for i = 1:size(array, 1)
                arrayString = strcat(arrayString, strjoin(string(array(i,:)), ', '), ';');
            end
            arrayString = arrayString{1}(1:end-1);
            arrayString = strcat(arrayString, '];\n');
            arrayString = convertStringsToChars(arrayString);
        end
        
        function featureDataStruct2Field(app, featureName)
            app.FeatureDescriptionTextArea.Value = app.subjectInfo.fields.(featureName).Description;
            app.FeatureUnitEditField.Value = app.subjectInfo.fields.(featureName).Unit;
        end
        
        function levelDataStruct2Field(app, featureName, levelName)
            app.LevelNameEditField.Value = app.subjectInfo.fields.(featureName).Levels.(levelName);
        end
        
        function tableString = generateConfigTable(app, table)
            tableString = '= {\n';
            for i = 1:size(table,1)
                for j = 1:size(table,2)
                    entry = table(i,j);
                    entry = entry{1};
                    if isnumeric(entry)
                        tableString = strcat(tableString, ['\t' num2str(entry) ', ']);
                    else
                        tableString = strcat(tableString, ['\t' '''' entry '''' ', ']);
                    end
                end
                tableString = strcat(tableString, ['... \n']);
            end
            tableString = strcat(tableString, ['};']);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SessionRidigbodyTable.Data = table({'SessionName'}, {''}, {''}, 'VariableNames', {'Name', 'PositionUnit', 'OrientationUnit'});
            app.sessRigidData = table2struct(app.SessionRidigbodyTable.Data);
        end

        % Button pushed function: StudyFolderBrowserButton
        function StudyFolderBrowserButtonPushed(app, event)
            selpath = uigetdir;
            app.StudyFolderEditField.Value = selpath;matlab
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, 'Session and Rigidbody')
                app.SessionRidigbodyTable.Data = struct2table(app.sessRigidData, "AsArray", true);
            else
                app.sessRigidData = table2struct(app.SessionRidigbodyTable.Data);
            end
        end

        % Cell selection callback: SessionRidigbodyTable
        function SessionRidigbodyTableCellSelection(app, event)
            indices = event.Indices;
            app.sessRigidTableRow = indices(1);
            app.sessRigidTableCol = indices(2); 
        end

        % Button pushed function: SessionAddButton
        function SessionAddButtonPushed(app, event)
            app.SessionRidigbodyTable.Data = [app.SessionRidigbodyTable.Data; [{'SessionName', '', ''}, repmat({1}, 1, width(app.SessionRidigbodyTable.Data)-3)]];
            app.sessRigidData = table2struct(app.SessionRidigbodyTable.Data);
        end

        % Button pushed function: SessionRemoveButton
        function SessionRemoveButtonPushed(app, event)
            if (height(app.SessionRidigbodyTable.Data) >= app.sessRigidTableRow) && app.sessRigidTableRow ~= 0
                app.SessionRidigbodyTable.Data(app.sessRigidTableRow, :) = [];
                app.sessRigidData = table2struct(app.SessionRidigbodyTable.Data);
            end
        end

        % Button pushed function: RigidAddButton
        function RigidAddButtonPushed(app, event)
            rigidBodyName = inputdlg('RigidBody Name');
            app.SessionRidigbodyTable.Data = [app.SessionRidigbodyTable.Data, repmat({1}, height(app.SessionRidigbodyTable.Data), 1)];
            app.SessionRidigbodyTable.ColumnName(end+1) = rigidBodyName;
            app.sessRigidData = table2struct(app.SessionRidigbodyTable.Data);
        end

        % Button pushed function: RigidRemoveButton
        function RigidRemoveButtonPushed(app, event)
            if (width(app.SessionRidigbodyTable.Data) >= app.sessRigidTableCol) && (app.sessRigidTableCol > 3)
                app.SessionRidigbodyTable.Data(:, app.sessRigidTableCol) = [];
                app.sessRigidData = table2struct(app.SessionRidigbodyTable.Data);
            end
        end

        % Button pushed function: SessionSortButton
        function SessionSortButtonPushed(app, event)
            
        end

        % Button pushed function: ConfigSaveButton
        function ConfigSaveButtonPushed(app, event)
            % Update the info structs
            generateConfigStructs(app);
            
            % Write bemobil_config
            fID = fopen("E:\BPN_Arbeit\bids-motion-gui\bemobil_config.m", 'wt');
            
            % General Info
            fprintf(fID, '%% General Info\n');
            fprintf(fID, '%% Required for dataset_description.json\n');
            fprintf(fID, ['generalInfo.dataset_description.Name' generateConfigString(app, app.generalInfo.dataset_description.Name)]);
            fprintf(fID, ['generalInfo.dataset_description.BIDSVersion ' generateConfigString(app, app.generalInfo.dataset_description.BIDSVersion)]);
            fprintf(fID, ['generalInfo.dataset_description.Acknowledgements ' generateConfigString(app, app.generalInfo.dataset_description.Acknowledgements)]);
            
            fprintf(fID, '%% optional for dataset_description.json\n');
            fprintf(fID, ['generalInfo.dataset_description.License ' generateConfigString(app, app.generalInfo.dataset_description.License)]);
            fprintf(fID, ['generalInfo.generalInfo.dataset_description.Authors  ' generateConfigString(app, app.generalInfo.dataset_description.Authors )]);
            fprintf(fID, ['generalInfo.dataset_description.Acknowledgements ' generateConfigString(app, app.generalInfo.dataset_description.Acknowledgements)]);
            fprintf(fID, ['generalInfo.dataset_description.Funding ' generateConfigString(app, app.generalInfo.dataset_description.Funding)]);
            fprintf(fID, ['generalInfo.dataset_description.ReferencesAndLinks ' generateConfigString(app, app.generalInfo.dataset_description.ReferencesAndLinks)]);
            fprintf(fID, ['generalInfo.dataset_description.DatasetDOI ' generateConfigString(app, app.generalInfo.dataset_description.DatasetDOI)]);

            fprintf(fID, '%% general information shared across modality specific json files \n');
            fprintf(fID, ['generalInfo.InstitutionName ' generateConfigString(app, app.generalInfo.InstitutionName)]);
            fprintf(fID, ['generalInfo.InstitutionalDepartmentName ' generateConfigString(app, app.generalInfo.InstitutionalDepartmentName)]);
            fprintf(fID, ['generalInfo.InstitutionAddress ' generateConfigString(app, app.generalInfo.InstitutionAddress)]);
            fprintf(fID, ['generalInfo.TaskDescription ' generateConfigString(app, app.generalInfo.TaskDescription)]);
            fprintf(fID, ['generalInfo.task ' generateConfigString(app, app.bemobil_config.bids_tasklabel)]);
        
            fprintf(fID, '\n');
            fprintf(fID, '%% Folder and file structure \n');
            fprintf(fID, ['bemobil_config.study_folder  ' generateConfigString(app, app.bemobil_config.study_folder )]);
            fprintf(fID, ['bemobil_config.filename_prefix ' generateConfigString(app, app.bemobil_config.filename_prefix)]);
            fprintf(fID, ['bemobil_config.raw_data_folder ' generateConfigString(app, app.bemobil_config.raw_data_folder)]);
            fprintf(fID, ['bemobil_config.bids_data_folder ' generateConfigString(app, app.bemobil_config.bids_data_folder)]);
            fprintf(fID, ['bemobil_config.channel_locations_filename ' generateConfigString(app, app.bemobil_config.channel_locations_filename)]);
            fprintf(fID, ['bemobil_config.bids_eegkeyword ' generateConfigString(app, app.bemobil_config.bids_eegkeyword)]);
            fprintf(fID, ['bemobil_config.bids_tasklabel ' generateConfigString(app, app.bemobil_config.bids_tasklabel)]);

            fprintf(fID, '\n');
            fprintf(fID, '%% Session and rigidbody info \n');
            fprintf(fID, ['bemobil_config.rigidbody_streams ' generateConfigCell(app, app.bemobil_config.rigidbody_streams )]);
            fprintf(fID, ['bemobil_config.filenames ' generateConfigCell(app, app.bemobil_config.filenames )]);
            fprintf(fID, ['bemobil_config.bids_motion_positionunits ' generateConfigCell(app, app.bemobil_config.bids_motion_positionunits)]);
            fprintf(fID, ['bemobil_config.bids_motion_orientationunits ' generateConfigCell(app, app.bemobil_config.bids_motion_orientationunits)]);
            fprintf(fID, ['bemobil_config.bids_rbsessions ' generateConfigArray(app, app.bemobil_config.bids_rbsessions)]);
            
            fprintf(fID, '\n');
            fprintf(fID, '%% EEG recording system \n');
            fprintf(fID, ['eegInfo.eeg.PowerLineFequency ' generateConfigString(app, app.eegInfo.eeg.PowerLineFequency)]);
            fprintf(fID, ['eegInfo.eeg.EEGReference ' generateConfigString(app, app.eegInfo.eeg.EEGReference)]);

            fprintf(fID, '\n');
            fprintf(fID, '%% Motion recording system \n');
            fprintf(fID, ['motionInfo.acq ' generateConfigString(app, app.motionInfo.acq)]);
            fprintf(fID, ['motionInfo.motion.Manufacturer ' generateConfigString(app, app.motionInfo.motion.Manufacturer)]);
            fprintf(fID, ['motionInfo.motion.ManufacturersModelName ' generateConfigString(app, app.motionInfo.motion.ManufacturersModelName)]);
            fprintf(fID, ['motionInfo.motion.RecordingType ' generateConfigString(app, app.motionInfo.motion.RecordingType)]);
            fprintf(fID, ['motionInfo.coordsystem.MotionCoordinateSystem ' generateConfigString(app, app.motionInfo.coordsystem.MotionCoordinateSystem)]);
            fprintf(fID, ['motionInfo.coordsystem.MotionRotationRule ' generateConfigString(app, app.motionInfo.coordsystem.MotionRotationRule)]);
            fprintf(fID, ['motionInfo.coordsystem.MotionRotationOrder ' generateConfigString(app, app.motionInfo.coordsystem.MotionRotationOrder)]);
            
            fprintf(fID, '\n');
            fprintf(fID, '%% Participant Info \n');
            features = fieldnames(app.subjectInfo.fields);
            for i = 1:length(features)
                fprintf(fID, ['subjectInfo.fields.' features{i} '.Description ' generateConfigString(app, app.subjectInfo.fields.(features{i}).Description)]);
                fprintf(fID, ['subjectInfo.fields.' features{i} '.Unit ' generateConfigString(app, app.subjectInfo.fields.(features{i}).Unit)]);
                if isfield(app.subjectInfo.fields.(features{i}),'Levels')
                    levels = fieldnames(app.subjectInfo.fields.(features{i}).Levels);
                    for j = 1:length(levels)
                        fprintf(fID, ['subjectInfo.fields.' features{i} '.Levels.' levels{j} ' ' generateConfigString(app, app.subjectInfo.fields.(features{i}).Levels.(levels{j}))]);
                    end
                end
            end
            fprintf(fID, '\n');
            fprintf(fID, ['subjectInfo.cols ' generateConfigCell(app, app.subjectInfo.cols )]);
            fprintf(fID, ['subjectInfo.data ' generateConfigTable(app, app.subjectInfo.data )]);
            fclose(fID);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            file = uigetfile('*.csv');
            opts = detectImportOptions(file);
            table = readtable(file, opts);
            app.SubjectDataTable.ColumnName = table.Properties.VariableNames;
            app.SubjectDataTable.Data = table;
        end

        % Button pushed function: SubjectFeatureSearchfromfileButton
        function SubjectFeatureSearchfromfileButtonPushed(app, event)
            if ~isempty(app.SubjectDataTable.Data)
                nodeNames = app.SubjectDataTable.ColumnName;
                subjectData = app.SubjectDataTable.Data;
                colCount = width(subjectData);
                
                for i = 1:length(nodeNames)
                    % Add field only if it doesn't already exist
                    if ~isfield(app.subjectInfo, 'fields') || ~isfield(app.subjectInfo.fields, nodeNames{i})
                        % Add feature nodes
                        node = uitreenode(app.SubjectFeatureTree, 'Text', nodeNames{i});
                        app.subjectInfo.fields.(nodeNames{i}).Description = '';
                        app.subjectInfo.fields.(nodeNames{i}).Unit = '';
                        
                        % Add Level nodes
                        levels =  table2array(unique(subjectData(:,i)));
                        
                        % Only add levels for non-numeric features
                        if ~isa(levels, 'numeric')
                            for j = 1:height(levels)
                                try 
                                    app.subjectInfo.fields.(nodeNames{i}).Levels.(string(levels(j))) = '';
                                    child = uitreenode(node, 'Text', string(levels(j)));
                                end
                            end
                        end
                    end
                end
            end
            
        end

        % Selection changed function: SubjectFeatureTree
        function SubjectFeatureTreeSelectionChanged(app, event)
            selectedNodes = app.SubjectFeatureTree.SelectedNodes;
            
            % If feature (root) node is selected
            if isequal(class(selectedNodes.Parent),  'matlab.ui.container.Tree')
                app.FeatureDescriptionPanel.Enable = 'on';
                app.LevelDescriptionPanel.Enable = 'off';
                featureName = selectedNodes.Text;
                app.activeFeature = featureName;
                app.featureDataStruct2Field(featureName);
            end
            
            % If level (branch) node is selected
            if isequal(class(selectedNodes.Parent),  'matlab.ui.container.TreeNode')
                app.LevelDescriptionPanel.Enable = 'on';
                app.FeatureDescriptionPanel.Enable = 'off';
                levelName = selectedNodes.Text;
                featureName = selectedNodes.Parent.Text;
                app.activeFeature = featureName;
                app.activeLevel = levelName;
                app.levelDataStruct2Field(featureName, levelName);
            end
        end

        % Button pushed function: SubjectFeatureAddButton
        function SubjectFeatureAddButtonPushed(app, event)
            featureName = inputdlg("Feature Name");
            if ~isempty(featureName)
                featureName = featureName{1};
                uitreenode(app.SubjectFeatureTree, 'Text', featureName);
                app.subjectInfo.fields.(featureName).Description = '';
                app.subjectInfo.fields.(featureName).Unit = '';
            end
        end

        % Button pushed function: SubjectFeatureRemoveButton
        function SubjectFeatureRemoveButtonPushed(app, event)
            selectedFeature = app.SubjectFeatureTree.SelectedNodes.Text;
            app.subjectInfo.fields = rmfield(app.subjectInfo.fields, selectedFeature);
            app.SubjectFeatureTree.SelectedNodes.delete;
        end

        % Button pushed function: SubjectFeatureEditButton
        function SubjectFeatureEditButtonPushed(app, event)
            if ~isempty(app.SubjectFeatureTree.SelectedNodes)
                selectedFeature = app.SubjectFeatureTree.SelectedNodes.Text;
                
                featureName = inputdlg("Edit Feature", "", [1 35], {selectedFeature});
                featureName = featureName{1};
                
                app.subjectInfo.fields.(featureName) = app.subjectInfo.fields.(selectedFeature);
                app.subjectInfo.fields = rmfield(app.subjectInfo.fields, selectedFeature);
                app.SubjectFeatureTree.SelectedNodes.Text = featureName;
            end
        end

        % Button pushed function: SubjectLevelAddButton
        function SubjectLevelAddButtonPushed(app, event)
            if ~isempty(app.SubjectFeatureTree.SelectedNodes) && isequal(class(app.SubjectFeatureTree.SelectedNodes.Parent),  'matlab.ui.container.Tree')
                selectedFeature = app.SubjectFeatureTree.SelectedNodes.Text;
                
                levelName = inputdlg("Level Name");
                if ~isempty(levelName)
                    levelName = levelName{1};
                    uitreenode(app.SubjectFeatureTree.SelectedNodes, 'Text', levelName);
                    app.subjectInfo.fields.(selectedFeature).Levels.(levelName) = '';
                end
            end
        end

        % Button pushed function: SubjectLevelEditButton
        function SubjectLevelEditButtonPushed(app, event)
            if ~isempty(app.SubjectFeatureTree.SelectedNodes) && isequal(class(app.SubjectFeatureTree.SelectedNodes.Parent),  'matlab.ui.container.TreeNode')
                selectedLevel = app.SubjectFeatureTree.SelectedNodes.Text;
                selectedFeature = app.SubjectFeatureTree.SelectedNodes.Parent.Text;
                
                levelName = inputdlg("Edit Feature", "", [1 35], {selectedLevel});
                levelName = levelName{1};
                
                app.subjectInfo.fields.(selectedFeature).Levels.(levelName) = app.subjectInfo.fields.(selectedFeature).Levels.(selectedLevel);
                app.subjectInfo.fields.(selectedFeature).Levels = rmfield(app.subjectInfo.fields.(selectedFeature).Levels, selectedLevel);
                app.SubjectFeatureTree.SelectedNodes.Text = levelName;
            end
        end

        % Button pushed function: SubjectLevelRemoveButton
        function SubjectLevelRemoveButtonPushed(app, event)
            if ~isempty(app.SubjectFeatureTree.SelectedNodes) && isequal(class(app.SubjectFeatureTree.SelectedNodes.Parent),  'matlab.ui.container.TreeNode')
                selectedLevel = app.SubjectFeatureTree.SelectedNodes.Text;
                selectedFeature = app.SubjectFeatureTree.SelectedNodes.Parent.Text;
                
                app.subjectInfo.fields.(selectedFeature).Levels = rmfield(app.subjectInfo.fields.(selectedFeature).Levels, selectedLevel);
                app.SubjectFeatureTree.SelectedNodes.delete;
            end
        end

        % Value changed function: LevelNameEditField
        function LevelNameEditFieldValueChanged(app, event)
            value = app.LevelNameEditField.Value;
            app.subjectInfo.fields.(app.activeFeature).Levels.(app.activeLevel) = value;
        end

        % Value changed function: FeatureDescriptionTextArea
        function FeatureDescriptionTextAreaValueChanged(app, event)
            value = app.FeatureDescriptionTextArea.Value;
            app.subjectInfo.fields.(app.activeFeature).Description = value;
        end

        % Value changed function: FeatureUnitEditField
        function FeatureUnitEditFieldValueChanged(app, event)
            value = app.FeatureUnitEditField.Value;
            app.subjectInfo.fields.(app.activeFeature).Unit = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.NameEditField_2Label = uilabel(app.GeneralTab);
            app.NameEditField_2Label.Text = 'Name';
            app.StudyNameEditField = uieditfield(app.GeneralTab, 'text');
            app.StudyNameEditField.Tooltip = {'Required'; '[string]'; 'Name of the task (for resting state use the “rest” prefix). No two tasks should have the same name. Task label is derived from this field by removing all non-alphanumeric ([a-zA-Z0-9]) characters. Note this does not have to be a “behavioral task” that subjects perform, but can reflect some information about the conditions present when the data was acquired (for example, “rest”, “seizure”, or “sleep”). '};
            app.StudyNameEditField.Value = 'Triangle completion task in an immersive VR and Desktop condition';
            app.BIDSVersionEditFieldLabel = uilabel(app.GeneralTab);
            app.BIDSVersionEditFieldLabel.Text = 'BIDS Version';
            app.BIDSVersionEditField = uieditfield(app.GeneralTab, 'text');
            app.BIDSVersionEditField.Value = 'unofficial extension';
            app.LicenseEditFieldLabel = uilabel(app.GeneralTab);
            app.LicenseEditFieldLabel.Text = 'License';
            app.LicenseEditField = uieditfield(app.GeneralTab, 'text');
            app.LicenseEditField.Value = 'CC by 4.0';
            app.AuthorsEditFieldLabel = uilabel(app.GeneralTab);
            app.AuthorsEditFieldLabel.Text = 'Authors';
            app.AuthorsEditField = uieditfield(app.GeneralTab, 'text');
            app.AuthorsEditField.Tooltip = {'unkown'; '[string]'; 'Persons responsible for the experiment and data collection.'};
            app.AuthorsEditField.Value = 'Timotheus Berg and Klaus Gramann';
            app.AcknowledgementsTextAreaLabel = uilabel(app.GeneralTab);
            app.AcknowledgementsTextAreaLabel.Text = 'Acknowledgements';
            app.AcknowledgementsTextArea = uitextarea(app.GeneralTab);
            app.AcknowledgementsTextArea.Tooltip = {'unkown'; '[string]'; 'Acknowledgements to persons/institutions involved in the creation of the data set.'};
            app.AcknowledgementsTextArea.Value = {'We thank Yiru Chen for assitance in the data collection'};
            app.FundingTextAreaLabel = uilabel(app.GeneralTab);
            app.FundingTextAreaLabel.Text = 'Funding';
            app.FundingTextArea = uitextarea(app.GeneralTab);
            app.FundingTextArea.Tooltip = {'unkown'; '[string]'; 'Funding of the project.'};
            app.FundingTextArea.Value = {'This Study was realized by funding from the German Research Foundation (DFG)'};
            app.ReferenceandLinksTextAreaLabel = uilabel(app.GeneralTab);
            app.ReferenceandLinksTextAreaLabel.Text = 'Reference and Links';
            app.ReferenceandLinksTextArea = uitextarea(app.GeneralTab);
            app.ReferenceandLinksTextArea.Tooltip = {'unknown'; '[string]'; 'URLs or other references of relevant information'};
            app.DatasetDOIEditFieldLabel = uilabel(app.GeneralTab);
            app.DatasetDOIEditFieldLabel.Text = 'Dataset DOI';
            app.DatasetDOIEditField = uieditfield(app.GeneralTab, 'text');
            app.DatasetDOIEditField.Tooltip = {'unknown'; '[string]'; 'DOI of the dataset provided by outlets such as figshare'};
            app.InstitutionNameEditFieldLabel = uilabel(app.GeneralTab);
            app.InstitutionNameEditFieldLabel.Text = 'Institution Name';
            app.InstitutionNameEditField = uieditfield(app.GeneralTab, 'text');
            app.InstitutionNameEditField.Tooltip = {'Recommended'; '[string]'; ''; 'The name of the institution in charge of the equipment that produced the composite instances.'};
            app.InstitutionNameEditField.Value = 'Technische Universitaet Berlin';
            app.DepartmentNameEditFieldLabel = uilabel(app.GeneralTab);
            app.DepartmentNameEditFieldLabel.Text = 'Department Name';
            app.DepartmentNameEditField = uieditfield(app.GeneralTab, 'text');
            app.DepartmentNameEditField.Tooltip = {'Recommended'; '[string]'; ''; 'The department in the institution in charge of the equipment that produced the composite instances.'};
            app.DepartmentNameEditField.Value = 'Chair of Biological Psychology and Neuroergonomics, Department of Psychology and Ergonomics';
            app.InstitutionAddressEditFieldLabel = uilabel(app.GeneralTab);
            app.InstitutionAddressEditFieldLabel.Text = 'Institution Address';
            app.InstitutionAddressEditField = uieditfield(app.GeneralTab, 'text');
            app.InstitutionAddressEditField.Tooltip = {'Recommended'; '[string]'; 'The address of the institution in charge of the equipment that produced the composite instances. '};
            app.InstitutionAddressEditField.Value = 'Fasanenstr. 1, 10623, Berlin, Germany';
            app.TaskDescriptionTextAreaLabel = uilabel(app.GeneralTab);
            app.TaskDescriptionTextAreaLabel.Text = 'Task Description';
            app.TaskDescriptionTextArea = uitextarea(app.GeneralTab);
            app.TaskDescriptionTextArea.Tooltip = {'Recommended'; '[string]'; 'Longer description of the task.'};
            app.TaskDescriptionTextArea.Value = {'Participants performed a triangle completion task in an environment without external landmarks. The participants where guided by a sphere to walk two legs of a triangle and were given the task of finding the origin of the walked path (i.e. start of the triangle). The task condition alternated with a baseline condition in which the participants were guided by the sphere to random points in the room.'};
            app.FolderStructureTab = uitab(app.TabGroup);
            app.FolderStructureTab.Title = 'Folder Structure';
            app.StudyFolderBrowserButton = uibutton(app.FolderStructureTab, 'push');
            app.StudyFolderBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @StudyFolderBrowserButtonPushed, true);
            app.StudyFolderBrowserButton.Text = '...';
            app.StudyFolderEditFieldLabel = uilabel(app.FolderStructureTab);
            app.StudyFolderEditFieldLabel.Text = 'Study Folder';
            app.StudyFolderEditField = uieditfield(app.FolderStructureTab, 'text');
            app.StudyFolderEditField.Editable = 'off';
            app.StudyFolderEditField.Tooltip = {'Required'; '[string]'; 'Folder path to where the data is stored. Should be the parent folder of the folder with the raw data.'};
            app.StudyFolderEditField.Value = 'E:\BPN_Arbeit\triangle_walking_admin\04_data\';
            app.FilenameprefixEditFieldLabel = uilabel(app.FolderStructureTab);
            app.FilenameprefixEditFieldLabel.Text = 'Filename prefix';
            app.FilenameprefixEditField = uieditfield(app.FolderStructureTab, 'text');
            app.FilenameprefixEditField.Tooltip = {'Required'; '[string]'; 'Prefix used for subjects/sessions.'};
            app.FilenameprefixEditField.Value = 's';
            app.RawDatafolderEditFieldLabel = uilabel(app.FolderStructureTab);
            app.RawDatafolderEditFieldLabel.Text = 'Raw Data folder';
            app.RawDatafolderEditField = uieditfield(app.FolderStructureTab, 'text');
            app.RawDatafolderEditField.Tooltip = {'Required'; '[string]'; 'Folder name of the raw data folder. Needs to be located in the study folder. It is recommended to add a "0_" prefix to the folder name for a clear folder structure.'};
            app.RawDatafolderEditField.Value = '0_raw-data\';
            app.ChannellocationsfilenameEditFieldLabel = uilabel(app.FolderStructureTab);
            app.ChannellocationsfilenameEditFieldLabel.Text = 'Channel locations filename';
            app.ChannellocationsfilenameEditField = uieditfield(app.FolderStructureTab, 'text');
            app.ChannellocationsfilenameEditField.Tooltip = {'Required'; '[string]'; 'Prefix of the file storing the channel locations. Underbar should be left out. '; 'E.g. "s1_locs.elc" -> "locs.elc"'};
            app.ChannellocationsfilenameEditField.Value = 'locs.elc';
            app.BIDSdatafolderEditFieldLabel = uilabel(app.FolderStructureTab);
            app.BIDSdatafolderEditFieldLabel.Text = 'BIDS data folder';
            app.BIDSdatafolderEditField = uieditfield(app.FolderStructureTab, 'text');
            app.BIDSdatafolderEditField.Tooltip = {'Required'; '[string]'; 'Folder name of folder that will store the data set in BIDS. "1_BIDS-data" is recommended.'};
            app.BIDSdatafolderEditField.Value = '1_BIDS-data';
            app.EEGkeywordEditFieldLabel = uilabel(app.FolderStructureTab);
            app.EEGkeywordEditFieldLabel.Text = 'EEG keyword';
            app.EEGkeywordEditField = uieditfield(app.FolderStructureTab, 'text');
            app.EEGkeywordEditField.Tooltip = {'Required'; '[string]'; 'Keyword of the EEG stream in the raw data file'};
            app.EEGkeywordEditField.Value = 'BrainVision';
            app.TasklabelEditFieldLabel = uilabel(app.FolderStructureTab);
            app.TasklabelEditFieldLabel.Text = 'Tasklabel';
            app.TasklabelEditField = uieditfield(app.FolderStructureTab, 'text');
            app.TasklabelEditField.Tooltip = {'Required'; '[string]'; ''};
            app.TasklabelEditField.Value = 'triangleWalk';
            app.SessionandRigidbodyTab = uitab(app.TabGroup);
            app.SessionandRigidbodyTab.Title = 'Session and Rigidbody';
            app.RigidbodyPanel = uipanel(app.SessionandRigidbodyTab);
            app.RigidbodyPanel.Title = 'Rigidbody';
            app.RigidAddButton = uibutton(app.RigidbodyPanel, 'push');
            app.RigidAddButton.ButtonPushedFcn = createCallbackFcn(app, @RigidAddButtonPushed, true);
            app.RigidAddButton.Text = 'Add';
            app.RigidRemoveButton = uibutton(app.RigidbodyPanel, 'push');
            app.RigidRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RigidRemoveButtonPushed, true);
            app.RigidRemoveButton.Text = 'Remove';
            app.SessionPanel = uipanel(app.SessionandRigidbodyTab);
            app.SessionPanel.Title = 'Session';
            app.SessionAddButton = uibutton(app.SessionPanel, 'push');
            app.SessionAddButton.ButtonPushedFcn = createCallbackFcn(app, @SessionAddButtonPushed, true);
            app.SessionAddButton.Text = 'Add';
            app.SessionRemoveButton = uibutton(app.SessionPanel, 'push');
            app.SessionRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @SessionRemoveButtonPushed, true);
            app.SessionRemoveButton.Text = 'Remove';
            app.SessionSortButton = uibutton(app.SessionPanel, 'push');
            app.SessionSortButton.ButtonPushedFcn = createCallbackFcn(app, @SessionSortButtonPushed, true);
            app.SessionSortButton.Text = 'Sort';
            app.SessionRidigbodyTable = uitable(app.SessionandRigidbodyTab);
            app.SessionRidigbodyTable.ColumnName = {'Name'; 'PositionUnit'; 'OrientationUnit'};
            app.SessionRidigbodyTable.RowName = {};
            app.SessionRidigbodyTable.ColumnEditable = true;
            app.SessionRidigbodyTable.CellSelectionCallback = createCallbackFcn(app, @SessionRidigbodyTableCellSelection, true);
            app.EEGTab = uitab(app.TabGroup);
            app.EEGTab.Title = 'EEG';
            app.PowerLineFrequencyEditFieldLabel = uilabel(app.EEGTab);
            app.PowerLineFrequencyEditFieldLabel.Text = 'Power Line Frequency';
            app.PowerLineFrequencyEditField = uieditfield(app.EEGTab, 'numeric');
            app.PowerLineFrequencyEditField.Tooltip = {'Required'; '[numerical]'; 'Value of the power line frequency.'};
            app.PowerLineFrequencyEditField.Value = 50;
            app.EEGReferenceEditFieldLabel = uilabel(app.EEGTab);
            app.EEGReferenceEditFieldLabel.Text = 'EEG Reference';
            app.EEGReferenceEditField = uieditfield(app.EEGTab, 'text');
            app.EEGReferenceEditField.Tooltip = {'Required'; '[string]'; 'Name of the EEG reference electrode'};
            app.EEGReferenceEditField.Value = 'REF';
            app.MotionSystemTab = uitab(app.TabGroup);
            app.MotionSystemTab.Title = 'Motion System';
            app.AcquisitionLabelEditFieldLabel = uilabel(app.MotionSystemTab);
            app.AcquisitionLabelEditFieldLabel.Text = 'Acquisition Label';
            app.AcquisitionLabelEditField = uieditfield(app.MotionSystemTab, 'text');
            app.AcquisitionLabelEditField.Value = 'Vive';
            app.ManufacturerEditFieldLabel = uilabel(app.MotionSystemTab);
            app.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            app.ManufacturerEditField = uieditfield(app.MotionSystemTab, 'text');
            app.ManufacturerEditField.Value = 'HTC';
            app.SystemNameEditFieldLabel = uilabel(app.MotionSystemTab);
            app.SystemNameEditFieldLabel.Text = 'System Name';
            app.SystemNameEditField = uieditfield(app.MotionSystemTab, 'text');
            app.SystemNameEditField.Value = 'Vive Pro';
            app.CoordinateSystemEditFieldLabel = uilabel(app.MotionSystemTab);
            app.CoordinateSystemEditFieldLabel.Text = 'Coordinate System';
            app.CoordinateSystemEditField = uieditfield(app.MotionSystemTab, 'text');
            app.CoordinateSystemEditField.Tooltip = {'Recommended'; '[string]'; 'Refers to the coordinate system in which the motion data are to be interpreted, if the recorded data can be mapped to a fixed reference frame. A sequence of characters F/B (forward-backward), L/R (left-right), and U/D (up-down). The position of a character in the sequence determines which of the X,Y, Z axes it maps to.'};
            app.CoordinateSystemEditField.Value = 'RUF';
            app.RotationRuleEditFieldLabel = uilabel(app.MotionSystemTab);
            app.RotationRuleEditFieldLabel.Text = 'Rotation Rule';
            app.RotationRuleEditField = uieditfield(app.MotionSystemTab, 'text');
            app.RotationRuleEditField.Tooltip = {'Recommned'; '[string]'; 'In case orientation channels are present, indicate whether rotations are applied clockwise around an axis when seen from the positive direction (left-hand rule) or counter-clockwise (right-hand rule).'};
            app.RotationRuleEditField.Value = 'left-hand';
            app.RotationOrderEditFieldLabel = uilabel(app.MotionSystemTab);
            app.RotationOrderEditFieldLabel.Text = 'Rotation Order';
            app.RotationOrderEditField = uieditfield(app.MotionSystemTab, 'text');
            app.RotationOrderEditField.Tooltip = {'Recommended'; '[string]'; 'Specify the sequence in which the elemental rotations are applied around the extrinsic axes. A 3D rotation is represented about 3 different axes (Tait-Bryan convention).'};
            app.RotationOrderEditField.Value = 'ZXY';
            app.RecordingTypeDropDownLabel = uilabel(app.MotionSystemTab);
            app.RecordingTypeDropDownLabel.Text = 'Recording Type';
            app.RecordingTypeDropDown = uidropdown(app.MotionSystemTab);
            app.RecordingTypeDropDown.Items = {'continuous', 'epoched', ''};
            app.RecordingTypeDropDown.Tooltip = {'Recommended'; '[string]'; 'Defines whether the recording is  “continuous” or  “epoched”; this latter limited to time windows about events of interest (e.g., stimulus presentations, subject responses etc.)'};
            app.RecordingTypeDropDown.Value = 'continuous';
            app.ParticipantTab = uitab(app.TabGroup);
            app.ParticipantTab.Title = 'Participant';
            app.TabGroup2 = uitabgroup(app.ParticipantTab);
            app.ValuesTab = uitab(app.TabGroup2);
            app.ValuesTab.Title = 'Values';
            app.SubjectDataTable = uitable(app.ValuesTab);
            app.SubjectDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SubjectDataTable.RowName = {};
            app.LoadDataButton = uibutton(app.ValuesTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.MetadataTab = uitab(app.TabGroup2);
            app.MetadataTab.Title = 'Metadata';
            app.FeatureDescriptionPanel = uipanel(app.MetadataTab);
            app.FeatureDescriptionPanel.Enable = 'off';
            app.FeatureDescriptionPanel.Title = 'Feature Description';
            app.FeatureUnitEditFieldLabel = uilabel(app.FeatureDescriptionPanel);
            app.FeatureUnitEditFieldLabel.Text = 'Feature Unit';
            app.FeatureUnitEditField = uieditfield(app.FeatureDescriptionPanel, 'text');
            app.FeatureUnitEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureUnitEditFieldValueChanged, true);
            app.FeatureDescriptionTextAreaLabel = uilabel(app.FeatureDescriptionPanel);
            app.FeatureDescriptionTextAreaLabel.Text = 'Feature Description';
            app.FeatureDescriptionTextArea = uitextarea(app.FeatureDescriptionPanel);
            app.FeatureDescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @FeatureDescriptionTextAreaValueChanged, true);
            app.FeaturesLevelsPanel = uipanel(app.MetadataTab);
            app.FeaturesLevelsPanel.Title = 'Features & Levels';
            app.FeaturesPanel = uipanel(app.FeaturesLevelsPanel);
            app.FeaturesPanel.Title = 'Features';
            app.SubjectFeatureSearchfromfileButton = uibutton(app.FeaturesPanel, 'push');
            app.SubjectFeatureSearchfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectFeatureSearchfromfileButtonPushed, true);
            app.SubjectFeatureSearchfromfileButton.Text = 'Search from file';
            app.SubjectFeatureRemoveButton = uibutton(app.FeaturesPanel, 'push');
            app.SubjectFeatureRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectFeatureRemoveButtonPushed, true);
            app.SubjectFeatureRemoveButton.Text = 'Remove';
            app.SubjectFeatureAddButton = uibutton(app.FeaturesPanel, 'push');
            app.SubjectFeatureAddButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectFeatureAddButtonPushed, true);
            app.SubjectFeatureAddButton.Text = 'Add';
            app.SubjectFeatureEditButton = uibutton(app.FeaturesPanel, 'push');
            app.SubjectFeatureEditButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectFeatureEditButtonPushed, true);
            app.SubjectFeatureEditButton.Text = 'Edit';
            app.SubjectFeatureTree = uitree(app.MetadataTab);
            app.SubjectFeatureTree.SelectionChangedFcn = createCallbackFcn(app, @SubjectFeatureTreeSelectionChanged, true);
            app.LevelsPanel = uipanel(app.MetadataTab);
            app.LevelsPanel.Title = 'Levels';
            app.SubjectLevelAddButton = uibutton(app.LevelsPanel, 'push');
            app.SubjectLevelAddButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectLevelAddButtonPushed, true);
            app.SubjectLevelAddButton.Text = 'Add';
            app.SubjectLevelRemoveButton = uibutton(app.LevelsPanel, 'push');
            app.SubjectLevelRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectLevelRemoveButtonPushed, true);
            app.SubjectLevelRemoveButton.Text = 'Remove';
            app.SubjectLevelEditButton = uibutton(app.LevelsPanel, 'push');
            app.SubjectLevelEditButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectLevelEditButtonPushed, true);
            app.SubjectLevelEditButton.Text = 'Edit';
            app.LevelDescriptionPanel = uipanel(app.MetadataTab);
            app.LevelDescriptionPanel.Enable = 'off';
            app.LevelDescriptionPanel.Title = 'Level Description';
            app.LevelNameEditFieldLabel = uilabel(app.LevelDescriptionPanel);
            app.LevelNameEditFieldLabel.Text = 'Level Name';
            app.LevelNameEditField = uieditfield(app.LevelDescriptionPanel, 'text');
            app.LevelNameEditField.ValueChangedFcn = createCallbackFcn(app, @LevelNameEditFieldValueChanged, true);
            app.ConfigSaveButton = uibutton(app.UIFigure, 'push');
            app.ConfigSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigSaveButtonPushed, true);
            app.ConfigSaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BIDS_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end